classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        speed_threshold_for_roaming     matlab.ui.control.NumericEditField
        speedthresholdforroamingLabel   matlab.ui.control.Label
        frame_windowEditField           matlab.ui.control.NumericEditField
        framewindowEditFieldLabel       matlab.ui.control.Label
        load_multi_mcd_button           matlab.ui.control.Button
        YixuanLiLabel                   matlab.ui.control.Label
        ThanksforHengRunhuiPingQiLabel  matlab.ui.control.Label
        s2frameEditField                matlab.ui.control.NumericEditField
        frame1sEditFieldLabel           matlab.ui.control.Label
        unit2umEditField                matlab.ui.control.NumericEditField
        unitofstagepostionumLabel       matlab.ui.control.Label
        pixel2umEditField               matlab.ui.control.NumericEditField
        pixelofthevideoumLabel          matlab.ui.control.Label
    end


    methods (Access = private)

        function my_add_path(app)

            % add the path of this .mlapp file to MATLAB

            cdpath = mfilename('fullpath');
            cdpath = fileparts(cdpath);
            cd(cdpath); % enter .m filepath
            addpath(genpath(cdpath)); % add path

        end

        function assign_value(app)

            % assign value to global variables

            global pixel2um unit2um s2frame frame_window speed_threshold_for_roaming
            pixel2um = app.pixel2umEditField.Value;
            unit2um = app.unit2umEditField.Value;
            s2frame = app.s2frameEditField.Value;
            frame_window = app.frame_windowEditField.Value;
            speed_threshold_for_roaming = app.speed_threshold_for_roaming.Value;

            global label_number_outlier label_number_beyond_edge label_number_human_flip label_number_forward_reversal
            label_number_outlier = 100;
            label_number_beyond_edge = 101;
            label_number_human_flip = 102;
            label_number_forward_reversal = 103;

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            my_add_path(app);
            assign_value(app);
        end

        % Callback function: not associated with a component
        function convert_yaml_to_mcdButtonPushed(app, event)
            convert_multiple_Yaml_to_Mcd;
        end

        % Button pushed function: load_multi_mcd_button
        function load_multi_mcd_buttonPushed(app, event)
            assign_value(app);
            process_multi_mcd;
        end

        % Callback function: not associated with a component
        function fix_the_wrong_coordinates_of_mcdmatButtonPushed(app, event)
            fix_coordinates_problem_of_mcd;
        end

        % Callback function: not associated with a component
        function addlabelintothevideotakeabout30sfor10000framesButtonPushed(app, event)
            add_label_into_the_video;
        end

        % Callback function: not associated with a component
        function fix_stop_startButtonPushed(app, event)
            stop_and_start;
        end

        % Callback function: not associated with a component
        function fix_head_tail_swapped_ButtonPushed(app, event)
            swap_the_machine_label_based_on_head_tail_swapped_CHECKED;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.pixelofthevideoumLabel = uilabel(app.UIFigure);
            app.pixelofthevideoumLabel.Text = '1 pixel of the video == ? um';
            app.pixel2umEditField = uieditfield(app.UIFigure, 'numeric');
            app.pixel2umEditField.Value = 1.683;
            app.unitofstagepostionumLabel = uilabel(app.UIFigure);
            app.unitofstagepostionumLabel.Text = '1 unit of the stage postion == ? um';
            app.unit2umEditField = uieditfield(app.UIFigure, 'numeric');
            app.unit2umEditField.Value = 0.05;
            app.frame1sEditFieldLabel = uilabel(app.UIFigure);
            app.frame1sEditFieldLabel.Text = '? frame == 1 s';
            app.s2frameEditField = uieditfield(app.UIFigure, 'numeric');
            app.s2frameEditField.Value = 66;
            app.ThanksforHengRunhuiPingQiLabel = uilabel(app.UIFigure);
            app.ThanksforHengRunhuiPingQiLabel.Text = 'Thanks for Heng, Runhui, Ping, Qi';
            app.YixuanLiLabel = uilabel(app.UIFigure);
            app.YixuanLiLabel.Text = 'Yixuan Li';
            app.load_multi_mcd_button = uibutton(app.UIFigure, 'push');
            app.load_multi_mcd_button.ButtonPushedFcn = createCallbackFcn(app, @load_multi_mcd_buttonPushed, true);
            app.load_multi_mcd_button.Text = 'load multi mcd and do machine label (take about 5s for 10000 frames)';
            app.framewindowEditFieldLabel = uilabel(app.UIFigure);
            app.framewindowEditFieldLabel.Text = 'frame window';
            app.frame_windowEditField = uieditfield(app.UIFigure, 'numeric');
            app.frame_windowEditField.Value = 10;
            app.speedthresholdforroamingLabel = uilabel(app.UIFigure);
            app.speedthresholdforroamingLabel.Text = 'speed threshold for roaming (mm/s)';
            app.speed_threshold_for_roaming = uieditfield(app.UIFigure, 'numeric');
            app.speed_threshold_for_roaming.Value = 0.05;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end